classdef sinyal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EKGSNYALUIFigure                matlab.ui.Figure
        MovingAvarageLowPassFiltredenGecmisSinyalButton  matlab.ui.control.Button
        PeakdeerleriiaretlenmiQRSsiyaliButton  matlab.ui.control.Button
        CombFilterButton                matlab.ui.control.Button
        PointMovingAvarageFiltreButton  matlab.ui.control.Button
        DcSinyalButton                  matlab.ui.control.Button
        HamSinyalButton                 matlab.ui.control.Button
        BOZOKBOYSSLabel                 matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function UIAxesButtonDown(app, event)
       
        end

        % Button pushed function: HamSinyalButton
        function HamSinyalButtonPushed(app, event)
        clc
        hamsinyal=load('ecg_60hz_200.dat'); 
        fig = axis;
        ax = uiaxes(fig);
        plot(ax,hamsinyal);

        end

        % Button pushed function: DcSinyalButton
        function DcSinyalButtonPushed(app, event)
            clc
            hamsinyal=load('ecg_60hz_200.dat'); 
            plot(hamsinyal); title('Ham Sinyal');
            dcsizsinyal=(hamsinyal-mean(hamsinyal));
            plot(dcsizsinyal); title('DC Bilesenleri Atilan Sinyal');
        end

        % Button pushed function: PointMovingAvarageFiltreButton
        function PointMovingAvarageFiltreButtonPushed(app, event)
           clc
hamsinyal=load('ecg_60hz_200.dat'); 
plot(hamsinyal); title('Ham Sinyal');
dcsizsinyal=(hamsinyal-mean(hamsinyal));
plot(dcsizsinyal); title('DC Bilesenleri Atilan Sinyal');
B=(1/10)*ones(1,10);
A=1;
freqz(B,A); 
title('10 Point Moving Avarage Filtre');
 
        end

        % Button pushed function: CombFilterButton
        function CombFilterButtonPushed(app, event)
            clc
hamsinyal=load('ecg_60hz_200.dat'); 
plot(hamsinyal); title('Ham Sinyal');
dcsizsinyal=(hamsinyal-mean(hamsinyal));
plot(dcsizsinyal); title('DC Bilesenleri Atilan Sinyal');
B=(1/10)*ones(1,10);
A=1;
freqz(B,A); 
title('10 Point Moving Avarage Filtre');
avaragefiltrelisinyal=filter(B,A,dcsizsinyal);
plot(avaragefiltrelisinyal) 
title('Moving Avarage (Low Pass) Filtreden Gecmis Sinyal');
B=conv([1 1],[0.6310 -0.2149 0.1512 -0.1288 0.1227 -0.1288 0.1512 -0.2149 0.6310]);
A=1;
freqz(B,A);
title('Comb Filter');
        end

        % Button pushed function: 
        % PeakdeerleriiaretlenmiQRSsiyaliButton
        function PeakdeerleriiaretlenmiQRSsiyaliButtonPushed(app, event)
clc
hamsinyal=load('ecg_60hz_200.dat'); 
plot(hamsinyal); title('Ham Sinyal');
dcsizsinyal=(hamsinyal-mean(hamsinyal));
plot(dcsizsinyal); title('DC Bilesenleri Atilan Sinyal');
B=(1/10)*ones(1,10);
A=1;
freqz(B,A); 
title('10 Point Moving Avarage Filtre');
avaragefiltrelisinyal=filter(B,A,dcsizsinyal);
plot(avaragefiltrelisinyal) 
title('Moving Avarage (Low Pass) Filtreden Gecmis Sinyal');
B=conv([1 1],[0.6310 -0.2149 0.1512 -0.1288 0.1227 -0.1288 0.1512 -0.2149 0.6310]);
A=1;
freqz(B,A);
title('Comb Filter');


comb=filter(B,A,avaragefiltrelisinyal);
plot(comb) 
title('(60Hz ve Harmoniklerini Bastiran) Filtreden Gecmis Sinyal');
t=comb(1:length(comb),1)>500;
degerler=comb(t);
plot(degerler)
title('QRS sinyalinin Peak Değerleri')
[pks,locs] = findpeaks(comb);
plot(degerler);
hold on; 
plot(locs,pks,'rv','MarkerFaceColor','r' );
title('Peak değerleri işaretlenmiş QRS siyali ');
hold off
beat_count=0; 
for k=2:length(degerler)-1
if(degerler(k)> degerler(k-1) && degerler(k) > degerler(k+1) && degerler(k) >1 )
disp('peak bulundu')
beat_count= beat_count+1; 
end
end

        end

        % Button pushed function: 
        % MovingAvarageLowPassFiltredenGecmisSinyalButton
        function MovingAvarageLowPassFiltredenGecmisSinyalButtonPushed(app, event)
            clc
hamsinyal=load('ecg_60hz_200.dat'); 
plot(hamsinyal); title('Ham Sinyal');
dcsizsinyal=(hamsinyal-mean(hamsinyal));
plot(dcsizsinyal); title('DC Bilesenleri Atilan Sinyal');
B=(1/10)*ones(1,10);
A=1;
freqz(B,A); 
title('10 Point Moving Avarage Filtre');
avaragefiltrelisinyal=filter(B,A,dcsizsinyal);
plot(avaragefiltrelisinyal) 
title('Moving Avarage (Low Pass) Filtreden Gecmis Sinyal');
        end

        % Callback function
        function scNfrekansButtonPushed(app, event)
            clc
hamsinyal=load('ecg_60hz_200.dat'); 
plot(hamsinyal); title('Ham Sinyal');
dcsizsinyal=(hamsinyal-mean(hamsinyal));
plot(dcsizsinyal); title('DC Bilesenleri Atilan Sinyal');
B=(1/10)*ones(1,10);
A=1;
freqz(B,A); 
title('10 Point Moving Avarage Filtre');
avaragefiltrelisinyal=filter(B,A,dcsizsinyal);
plot(avaragefiltrelisinyal) 
title('Moving Avarage (Low Pass) Filtreden Gecmis Sinyal');
B=conv([1 1],[0.6310 -0.2149 0.1512 -0.1288 0.1227 -0.1288 0.1512 -0.2149 0.6310]);
A=1;
freqz(B,A);
title('Comb Filter');


comb=filter(B,A,avaragefiltrelisinyal);
plot(comb) 
title('(60Hz ve Harmoniklerini Bastiran) Filtreden Gecmis Sinyal');
t=comb(1:length(comb),1)>500;
degerler=comb(t);

beat_count=0; 
for k=2:length(degerler)-1
if(degerler(k)> degerler(k-1) && degerler(k) > degerler(k+1) && degerler(k) >1 )
disp('peak bulundu')
beat_count= beat_count+1;

end
end
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown2(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EKGSNYALUIFigure = uifigure('Visible', 'off');
            app.EKGSNYALUIFigure.Name = 'EKG SİNYALİ';
            app.UIAxes = uiaxes(app.EKGSNYALUIFigure);
            title(app.UIAxes, {'Sinyal Analiz'; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown2, true);
            app.BOZOKBOYSSLabel = uilabel(app.EKGSNYALUIFigure);
            app.BOZOKBOYSSLabel.Text = {'BOZOK BOYSİS'; ''};
            app.HamSinyalButton = uibutton(app.EKGSNYALUIFigure, 'push');
            app.HamSinyalButton.ButtonPushedFcn = createCallbackFcn(app, @HamSinyalButtonPushed, true);
            app.HamSinyalButton.Text = 'Ham Sinyal';
            app.DcSinyalButton = uibutton(app.EKGSNYALUIFigure, 'push');
            app.DcSinyalButton.ButtonPushedFcn = createCallbackFcn(app, @DcSinyalButtonPushed, true);
            app.DcSinyalButton.Text = 'Dc Sinyal';
            app.PointMovingAvarageFiltreButton = uibutton(app.EKGSNYALUIFigure, 'push');
            app.PointMovingAvarageFiltreButton.ButtonPushedFcn = createCallbackFcn(app, @PointMovingAvarageFiltreButtonPushed, true);
            app.PointMovingAvarageFiltreButton.Text = '10 Point Moving Avarage Filtre';
            app.CombFilterButton = uibutton(app.EKGSNYALUIFigure, 'push');
            app.CombFilterButton.ButtonPushedFcn = createCallbackFcn(app, @CombFilterButtonPushed, true);
            app.CombFilterButton.Text = 'Comb Filter';
            app.PeakdeerleriiaretlenmiQRSsiyaliButton = uibutton(app.EKGSNYALUIFigure, 'push');
            app.PeakdeerleriiaretlenmiQRSsiyaliButton.ButtonPushedFcn = createCallbackFcn(app, @PeakdeerleriiaretlenmiQRSsiyaliButtonPushed, true);
            app.PeakdeerleriiaretlenmiQRSsiyaliButton.Text = 'Peak değerleri işaretlenmiş QRS siyali ';
            app.MovingAvarageLowPassFiltredenGecmisSinyalButton = uibutton(app.EKGSNYALUIFigure, 'push');
            app.MovingAvarageLowPassFiltredenGecmisSinyalButton.ButtonPushedFcn = createCallbackFcn(app, @MovingAvarageLowPassFiltredenGecmisSinyalButtonPushed, true);
            app.MovingAvarageLowPassFiltredenGecmisSinyalButton.Text = 'Moving Avarage (Low Pass) Filtreden Gecmis Sinyal';
            % Show the figure after all components are created
            app.EKGSNYALUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sinyal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EKGSNYALUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EKGSNYALUIFigure)
        end
    end
end